classdef Test4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TEST4                matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        M1                   matlab.ui.container.Tab
        Label_plot3          matlab.ui.control.Label
        Label_plot2          matlab.ui.control.Label
        Label_plot1          matlab.ui.control.Label
        TextArea_actual      matlab.ui.control.TextArea
        Label_2              matlab.ui.control.Label
        TextArea_command     matlab.ui.control.TextArea
        Label                matlab.ui.control.Label
        Panel                matlab.ui.container.Panel
        UITable              matlab.ui.control.Table
        TextArea_filename    matlab.ui.control.TextArea
        Button_getfile       matlab.ui.control.Button
        UIAxes_command       matlab.ui.control.UIAxes
        UIAxes_ALL           matlab.ui.control.UIAxes
        M5                   matlab.ui.container.Tab
        Label_plot3_2        matlab.ui.control.Label
        Label_plot2_2        matlab.ui.control.Label
        Label_plot1_2        matlab.ui.control.Label
        TextArea_actual_2    matlab.ui.control.TextArea
        TextArea_4Label      matlab.ui.control.Label
        TextArea_command_2   matlab.ui.control.TextArea
        TextArea_3Label      matlab.ui.control.Label
        Panel_2              matlab.ui.container.Panel
        UITable_2            matlab.ui.control.Table
        TextArea_filename_2  matlab.ui.control.TextArea
        Button_getfile_2     matlab.ui.control.Button
        UIAxes_command_2     matlab.ui.control.UIAxes
        UIAxes_ALL_2         matlab.ui.control.UIAxes
        M25                  matlab.ui.container.Tab
        Label_plot3_3        matlab.ui.control.Label
        Label_plot2_3        matlab.ui.control.Label
        Label_plot1_3        matlab.ui.control.Label
        TextArea_actual_3    matlab.ui.control.TextArea
        TextArea_6Label      matlab.ui.control.Label
        TextArea_command_3   matlab.ui.control.TextArea
        TextArea_5Label      matlab.ui.control.Label
        Panel_3              matlab.ui.container.Panel
        UITable_3            matlab.ui.control.Table
        TextArea_filename_3  matlab.ui.control.TextArea
        Button_getfile_3     matlab.ui.control.Button
        UIAxes_command_3     matlab.ui.control.UIAxes
        UIAxes_ALL_3         matlab.ui.control.UIAxes
        Bode                 matlab.ui.container.Tab
        bodeButton           matlab.ui.control.Button
        UIAxes_bode          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Mag_all = ["M1","M5","M25"]  % Description
        des_plot1 = '阀芯控制信号：黑色；阀芯反馈信号：红色';
        des_plot2='阀芯控制位移2-5周期的实测曲线，黑色;阀芯控制位移2-5周期的拟合曲线，蓝色';
        des_plot3 ='阀芯反馈位移2-5周期的实测曲线，红色;阀芯反馈位移2-5周期的拟合曲线，绿色';
    end
  
    methods (Access = private)
        
        function results = cutdata(app,Mag,showfile,fretable)
            % 使用uigetfile来弹出文件选择对话框
            [Fname,Fpath] = uigetfile('.dbf');  %打开文件浏览器，选择.dbf文件
            filename = strcat(Fpath,Fname);    %获取包含地址的文件名
            showfile.Value = filename;   %文件名赋值给文本框
            sheetname = strrep(Fname,'.dbf','');
            Y1 = xlsread(filename,sheetname);
            
            P_A=1;  %A口压力所在列
            P_B=2;  %B口压力所在列
            P_P=3;  %C口压力所在列
            P_T=4;  %T口压力所在列
            Flow=5; %流量所在列
            Spool_dis_Command=6;    %阀芯控制位移所在列
            Spool_dis_Actual=7;     %阀芯反馈位移所在列
            Fs = 500;               %采样频率500
            [m,n]=size(Y1);         %采集数据的维度
            
            %获取阀芯控制信号改变点,存在Index里。便于对后续数据段进行分段处理
            Index=[];               %记录阀芯控制信号转变的行号
            j=1;
            for i=1:m-2
                first = Y1(i,Spool_dis_Command);
                second =  Y1(i+1,Spool_dis_Command);
                third = Y1(i+2,Spool_dis_Command);
                if first == 0 && second == 0 && third ~= 0
                    Index(j,1)=i+1;
                elseif first ~= 0 && second == 0 && third == 0
                    Index(j,2)=i+1;
                    j=j+1;
                end
            end
            
            %对第k段阀芯控制信号进行fft分析，得到第k段信号的大致频率主值，存在fmax里。
            for k=1:length(Index(:,1))
                % 对第k段信号进行分析
                x = Y1(Index(k,1):Index(k,2),Spool_dis_Command);      %待分析信号
                ALL_CommandCut(1:(Index(k,2)-Index(k,1)+1),k) =x;
                x2 = Y1(Index(k,1):Index(k,2),Spool_dis_Actual);
                ALL_ActualCut(1:(Index(k,2)-Index(k,1)+1),k) =x2;
                % 执行FFT变换
                N = length(x);  %信号长度
                X = fft(x,N);
                X = abs (X/N);
                X = X(1:round(N/2)+1);
                X(2:end-1)=2*X(2:end-1);
                % %如果想画频谱图，可以把下面代码取消注释
                % n = 0:length(X)-1;
                % f = n * Fs / N;
                % plot(f,X); 
                % 找到最大功率谱线的索引
                [~,I] = max(X);
                % 计算主频率
                fmax(k,1) = (I-1)*(Fs/N);
                % 输出主频率
                %disp(['主频率: ', num2str(fmax(k,1)), ' Hz']);
            end
            
            %对第k段信号进行删减（删减得到对应频率下2-5个周期的采样数据），删减后的阀芯控制信号存于Command_cut，删减后的阀芯实际信号存于Actual_cut。
            %拟合函数：a+b*sin(2*pi*d*t+c);阀芯控制信号拟合后的系数存于Command_fit。阀芯实际信号拟合后的系数存于Actual_fit。1-4列分别存储a,b,c,d。
            for k=1:length(Index(:,1))
                N1(1,k) = round(Fs/fmax(k,1));          %计算看看第k段信号，一个周期大致有几个采样点
                N2 = N1(1,k);
                i=1;
                %对第k段信号进行删减，对信号采集后大致第2-5个周期的信号进行后续拟合分析
                for i=1: 4* N2
                    Command_cut(i,k) = Y1(Index(k,1)+i-1+ N2,Spool_dis_Command);
                    Actual_cut(i,k)  = Y1(Index(k,1)+i-1+ N2,Spool_dis_Actual);
                end            
                % 对阀芯控制正弦信号进行拟合,拟合函数：a + b*sin(2*pi*d*t+c);用Command_fit函数的1-4列分别存储a,b,c,d。
                Command_fit(k,1:4) = fit_sin(app,Command_cut(1:4* N2,k),Fs,fmax(k,1));
                Actual_fit(k,1:4) = fit_sin(app,Actual_cut(1:4* N2,k),Fs,fmax(k,1)); 
                t  = (0:1/Fs:4* N2/Fs-1/Fs)';
                y_reconstructed_Command(1:4* N2,k) = Command_fit(k,1) + Command_fit(k,2)*sin(2*pi*Command_fit(k,4)*t+Command_fit(k,3));
                y_reconstructed_Actual(1:4* N2,k) = Actual_fit(k,1) + Actual_fit(k,2)*sin(2*pi*Actual_fit(k,4)*t+Actual_fit(k,3));  
            end
            
            xlswrite(strcat(Mag,'_ALL_CommandCut.xlsx'), ALL_CommandCut);
            xlswrite(strcat(Mag,'_ALL_ActualCut.xlsx'), ALL_ActualCut);
            xlswrite(strcat(Mag,'_Index.xlsx'), Index);
            xlswrite(strcat(Mag,'_N1.xlsx'), N1);
            xlswrite(strcat(Mag,'_fmax.xlsx'), fmax);
            xlswrite(strcat(Mag,'_Command_cut.xlsx'), Command_cut);
            xlswrite(strcat(Mag,'_Actual_cut.xlsx'), Actual_cut);
            xlswrite(strcat(Mag,'_Command_fit.xlsx'), Command_fit);
            xlswrite(strcat(Mag,'_Actual_fit.xlsx'), Actual_fit);
            xlswrite(strcat(Mag,'_y_reconstructed_Command.xlsx'), y_reconstructed_Command);
            xlswrite(strcat(Mag,'_y_reconstructed_Actual.xlsx'),  y_reconstructed_Actual);
            
            fmax_1(:,1) = 1:1:length(fmax);
            fmax_1(:,2) = fmax(:,1);
            fretable.Data= fmax_1;
        end


        function results = fit_sin(app,y,fs,fmax)
            %对阀芯控制正弦信号进行拟合,拟合函数：a + b*sin(2*pi*d*t+c);用Command_fit函数的1-4列分别存储a,b,c,d。
            N  = length(y);
            t  = (0:1/fs:N/fs-1/fs)';
            
            cfun    = @(d) [ones(size(t)), sin(2*pi*d*t), cos(2*pi*d*t)]\y;
            sumerr2 = @(d) sum((y - [ones(size(t)), sin(2*pi*d*t), cos(2*pi*d*t)] * cfun(d)) .^ 2);
            dopt    = fminbnd(sumerr2, fmax*0.9, fmax*1.1);
            abb     = cfun(dopt);
            
            a = abb(1);
            b = norm(abb([2 3]));
            c = acos(abb(2) / b);
            d = dopt;
            
            y_1 = a + b*sin(2*pi*d*t+c);
            y_2 = a + b*sin(2*pi*d*t-c);
            y_sum_1= sum( (y-  y_1).^ 2);
            y_sum_2= sum( (y-  y_2).^ 2);
            if y_sum_1< y_sum_2
                c = acos(abb(2) / b);
            else
                c = -acos(abb(2) / b);
            end
            results = [a,b,c,d];
        end
        
        function results = plotfigure(app,Mag,indices,figure1,figure2,data1,data2)
            %对分析得到的数据进行查看，看拟合数据是否满足要求。
            
            ALL_CommandCut = xlsread(strcat(Mag,'_ALL_CommandCut','.xlsx'));
            ALL_ActualCut = xlsread(strcat(Mag,'_ALL_ActualCut','.xlsx'));
            Command_cut = xlsread(strcat(Mag,'_Command_cut','.xlsx'));
            y_reconstructed_Command = xlsread(strcat(Mag,'_y_reconstructed_Command','.xlsx'));
            Actual_cut = xlsread(strcat(Mag,'_Actual_cut','.xlsx'));
            y_reconstructed_Actual = xlsread(strcat(Mag,'_y_reconstructed_Actual','.xlsx'));
            Index = xlsread(strcat(Mag,'_Index','.xlsx'));
            N1 = xlsread(strcat(Mag,'_N1','.xlsx'));
            fmax = xlsread(strcat(Mag,'_fmax','.xlsx'));
            Command_fit = xlsread(strcat(Mag,'_Command_fit','.xlsx'));
            Actual_fit = xlsread(strcat(Mag,'_Actual_fit','.xlsx'));
            
            %查看第k组的阀芯控制位移、阀芯实际位移的实际采样曲线
            k=indices(1);
            f_now = num2str(fmax(k,1));
            Fs =500;
            N_all = Index(k,2)-Index(k,1);
            t=[];
            t=0:1/Fs:N_all/Fs-1/Fs;
            cla(figure1);
            plot(figure1,t,ALL_CommandCut(1:N_all,k),'black');
            hold (figure1,'on');
            plot(figure1,t,ALL_ActualCut(1:N_all,k),'r');
            
            
            data1.Value = strcat(num2str(Command_fit(k,1)),"+",num2str(Command_fit(k,2)),"*sin(2*π*",num2str(Command_fit(k,4)),"*t+",num2str(Command_fit(k,3)),")") ;
            data2.Value = strcat(num2str(Actual_fit(k,1)),"+",num2str(Actual_fit(k,2)),"*sin(2*π*",num2str(Actual_fit(k,4)),"*t+",num2str(Actual_fit(k,3)),")") ;
            %查看第k组的阀芯控制位移、阀芯实际位移的采样曲线和拟合曲线
            
            t=[];
            t=0:1/Fs:4*N1(1,k)/Fs-1/Fs;
            
            cla(figure2);
            plot (figure2,t,Command_cut(1:4* N1(1,k),k),'black');    %阀芯控制位移的采样曲线
            hold (figure2,'on');
            plot (figure2,t,Actual_cut(1:4*N1(1,k),k),'red');        %阀芯实际位移的采样曲线
            hold (figure2,'on');
            plot (figure2,t,y_reconstructed_Command(1:4* N1(1,k),k),'blue'); %阀芯控制位移的拟合曲线
            hold (figure2,'on');
            plot (figure2,t, y_reconstructed_Actual(1:4* N1(1,k),k),'green'); %阀芯实际位移的拟合曲线
            hold (figure2,'on');
       
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label_plot1.Text = app.des_plot1;
            app.Label_plot2.Text= app.des_plot2;
            app.Label_plot3.Text= app.des_plot3;
            app.Label_plot1_2.Text = app.des_plot1;
            app.Label_plot2_2.Text= app.des_plot2;
            app.Label_plot3_2.Text= app.des_plot3;
            app.Label_plot1_3.Text = app.des_plot1;
            app.Label_plot2_3.Text= app.des_plot2;
            app.Label_plot3_3.Text= app.des_plot3;
        end

        % Callback function
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end

        % Button pushed function: Button_getfile
        function Button_getfilePushed(app, event)
            Mag =app.Mag_all(1,1);
            fretable=app.UITable;
            showfile=app.TextArea_filename;
            cutdata(app,Mag,showfile,fretable);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices; %Indices(1)代表选中的行数，Indices(2)代表选中的列数
            %对分析得到的数据进行查看，看拟合数据是否满足要求。
            Mag = app.Mag_all(1,1); %Mag取值为M1、M5、M25。分别表示对激励幅值为1%、5%、25%的曲线进行查看。
            figure1=app.UIAxes_ALL;
            figure2=app.UIAxes_command;
            data1=app.TextArea_command;
            data2=app.TextArea_actual;
            plotfigure(app,Mag,indices,figure1,figure2,data1,data2);
           
        end

        % Button pushed function: Button_getfile_2
        function Button_getfile_2Pushed(app, event)
            Mag =app.Mag_all(1,2);
            fretable=app.UITable_2;
            showfile=app.TextArea_filename_2;
            cutdata(app,Mag,showfile,fretable);
        end

        % Button pushed function: Button_getfile_3
        function Button_getfile_3Pushed(app, event)
            Mag =app.Mag_all(1,3);
            fretable=app.UITable_3;
            showfile=app.TextArea_filename_3;
            cutdata(app,Mag,showfile,fretable);
        end

        % Cell selection callback: UITable_2
        function UITable_2CellSelection(app, event)
            indices = event.Indices;
            %对分析得到的数据进行查看，看拟合数据是否满足要求。
            Mag = app.Mag_all(1,2); %Mag取值为M1、M5、M25。分别表示对激励幅值为1%、5%、25%的曲线进行查看。
            figure1=app.UIAxes_ALL_2;
            figure2=app.UIAxes_command_2;
            data1=app.TextArea_command_2;
            data2=app.TextArea_actual_2;
            plotfigure(app,Mag,indices,figure1,figure2,data1,data2);
            
        end

        % Cell selection callback: UITable_3
        function UITable_3CellSelection(app, event)
            indices = event.Indices;
            %对分析得到的数据进行查看，看拟合数据是否满足要求。
            Mag = app.Mag_all(1,3); %Mag取值为M1、M5、M25。分别表示对激励幅值为1%、5%、25%的曲线进行查看。
            figure1=app.UIAxes_ALL_3;
            figure2=app.UIAxes_command_3;
            data1=app.TextArea_command_3;
            data2=app.TextArea_actual_3;
            plotfigure(app,Mag,indices,figure1,figure2,data1,data2);
            
        end

        % Button pushed function: bodeButton
        function bodeButtonPushed(app, event)
            color = ["r","g","b"];
            for i = 1:length(app.Mag_all)
                Mag_1 = app.Mag_all(1,i);
                Command_fit = xlsread(strcat(Mag_1,'_Command_fit','.xlsx'));
                Actual_fit = xlsread(strcat(Mag_1,'_Actual_fit','.xlsx'));
                Y_Mag =20*log( Actual_fit(:,2) ./ Command_fit(:,2));
                yyaxis(app.UIAxes_bode,'left');
                plot (app.UIAxes_bode, Command_fit(:,4),Y_Mag,strcat(color(1,i),'-*'));
                ylim(app.UIAxes_bode,[-30 5])
                xlabel(app.UIAxes_bode,'频率（Hz）');
                ylabel(app.UIAxes_bode,'振幅比（db）');
                hold (app.UIAxes_bode,'on');
                yyaxis(app.UIAxes_bode,'right');
                Y_Pha =(-Actual_fit(:,3) +Command_fit(:,3))/ pi * 180;
                Y_Pha = mod(Y_Pha+360, 360);
                plot (app.UIAxes_bode,Command_fit(:,4),Y_Pha,strcat(color(1,i),'-o'));
                ylim(app.UIAxes_bode,[0 330])
                ylabel(app.UIAxes_bode,'相位差（°）');
                hold (app.UIAxes_bode,'on');
                set(app.UIAxes_bode, "xscale", "log");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TEST4 = uifigure('Visible', 'off');
            app.TEST4.Name = 'BODE Analyisis';
            app.TabGroup = uitabgroup(app.TEST4);
            app.M1 = uitab(app.TabGroup);
            app.M1.Title = '幅值1%';
            app.UIAxes_ALL = uiaxes(app.M1);
            title(app.UIAxes_ALL, '阀芯控制、阀芯反馈实测数据-总体')
            xlabel(app.UIAxes_ALL, '时间')
            ylabel(app.UIAxes_ALL, '幅值')
            app.UIAxes_command = uiaxes(app.M1);
            title(app.UIAxes_command, '阀芯控制、阀芯反馈的实测及拟合数据（选取每段测试的2-5个周期进行拟合）')
            xlabel(app.UIAxes_command, '时间')
            ylabel(app.UIAxes_command, '幅值')
            app.Button_getfile = uibutton(app.M1, 'push');
            app.Button_getfile.ButtonPushedFcn = createCallbackFcn(app, @Button_getfilePushed, true);
            app.Button_getfile.Text = '选择文件';
            app.TextArea_filename = uitextarea(app.M1);
            app.Panel = uipanel(app.M1);
            app.Panel.Title = '包含的测试频率值';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'序号'; '频率值'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Label = uilabel(app.M1);
            app.Label.Text = '阀芯控制拟合函数';
            app.TextArea_command = uitextarea(app.M1);
            app.Label_2 = uilabel(app.M1);
            app.Label_2.Text = '阀芯反馈拟合函数';
            app.TextArea_actual = uitextarea(app.M1);
            app.Label_plot1 = uilabel(app.M1);
            app.Label_plot2 = uilabel(app.M1);
            app.Label_plot2.Text = 'Label2';
            app.Label_plot3 = uilabel(app.M1);
            app.Label_plot3.Text = 'Label3';
            app.M5 = uitab(app.TabGroup);
            app.M5.Title = '幅值5%';
            app.UIAxes_ALL_2 = uiaxes(app.M5);
            title(app.UIAxes_ALL_2, '阀芯控制、阀芯反馈实测数据-总体')
            xlabel(app.UIAxes_ALL_2, '时间')
            ylabel(app.UIAxes_ALL_2, '幅值')
            app.UIAxes_command_2 = uiaxes(app.M5);
            title(app.UIAxes_command_2, '阀芯控制、阀芯反馈的实测及拟合数据（选取每段测试的2-5个周期进行拟合）')
            xlabel(app.UIAxes_command_2, '时间')
            ylabel(app.UIAxes_command_2, '幅值')
            app.Button_getfile_2 = uibutton(app.M5, 'push');
            app.Button_getfile_2.ButtonPushedFcn = createCallbackFcn(app, @Button_getfile_2Pushed, true);
            app.Button_getfile_2.Text = '选择文件';
            app.TextArea_filename_2 = uitextarea(app.M5);
            app.Panel_2 = uipanel(app.M5);
            app.Panel_2.Title = '包含的测试频率值';
            app.UITable_2 = uitable(app.Panel_2);
            app.UITable_2.ColumnName = {'序号'; '频率值'};
            app.UITable_2.RowName = {};
            app.UITable_2.CellSelectionCallback = createCallbackFcn(app, @UITable_2CellSelection, true);
            app.TextArea_3Label = uilabel(app.M5);
            app.TextArea_3Label.Text = '阀芯控制拟合函数';
            app.TextArea_command_2 = uitextarea(app.M5);
            app.TextArea_4Label = uilabel(app.M5);
            app.TextArea_4Label.Text = '阀芯反馈拟合函数';
            app.TextArea_actual_2 = uitextarea(app.M5);
            app.Label_plot1_2 = uilabel(app.M5);
            app.Label_plot2_2 = uilabel(app.M5);
            app.Label_plot2_2.Text = 'Label2';
            app.Label_plot3_2 = uilabel(app.M5);
            app.Label_plot3_2.Text = 'Label3';
            app.M25 = uitab(app.TabGroup);
            app.M25.Title = '幅值25%';
            app.UIAxes_ALL_3 = uiaxes(app.M25);
            title(app.UIAxes_ALL_3, '阀芯控制、阀芯反馈实测数据-总体')
            xlabel(app.UIAxes_ALL_3, '时间')
            ylabel(app.UIAxes_ALL_3, '幅值')
            app.UIAxes_command_3 = uiaxes(app.M25);
            title(app.UIAxes_command_3, '阀芯控制、阀芯反馈的实测及拟合数据（选取每段测试的2-5个周期进行拟合）')
            xlabel(app.UIAxes_command_3, '时间')
            ylabel(app.UIAxes_command_3, '幅值')
            app.Button_getfile_3 = uibutton(app.M25, 'push');
            app.Button_getfile_3.ButtonPushedFcn = createCallbackFcn(app, @Button_getfile_3Pushed, true);
            app.Button_getfile_3.Text = '选择文件';
            app.TextArea_filename_3 = uitextarea(app.M25);
            app.Panel_3 = uipanel(app.M25);
            app.Panel_3.Title = '包含的测试频率值';
            app.UITable_3 = uitable(app.Panel_3);
            app.UITable_3.ColumnName = {'序号'; '频率值'};
            app.UITable_3.RowName = {};
            app.UITable_3.CellSelectionCallback = createCallbackFcn(app, @UITable_3CellSelection, true);
            app.TextArea_5Label = uilabel(app.M25);
            app.TextArea_5Label.Text = '阀芯控制拟合函数';
            app.TextArea_command_3 = uitextarea(app.M25);
            app.TextArea_6Label = uilabel(app.M25);
            app.TextArea_6Label.Text = '阀芯反馈拟合函数';
            app.TextArea_actual_3 = uitextarea(app.M25);
            app.Label_plot1_3 = uilabel(app.M25);
            app.Label_plot2_3 = uilabel(app.M25);
            app.Label_plot2_3.Text = 'Label2';
            app.Label_plot3_3 = uilabel(app.M25);
            app.Label_plot3_3.Text = 'Label3';
            app.Bode = uitab(app.TabGroup);
            app.Bode.Title = 'Bode图';
            app.UIAxes_bode = uiaxes(app.Bode);
            title(app.UIAxes_bode, '频响特性曲线')
            xlabel(app.UIAxes_bode, '频率')
            ylabel(app.UIAxes_bode, '幅值比')
            app.UIAxes_bode.XTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxes_bode.YTick = [-30 -25 -20 -15 -10 -5 0 5];
            app.bodeButton = uibutton(app.Bode, 'push');
            app.bodeButton.ButtonPushedFcn = createCallbackFcn(app, @bodeButtonPushed, true);
            app.bodeButton.Text = '画bode图';
            % Show the figure after all components are created
            app.TEST4.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Test4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TEST4)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TEST4)
        end
    end
end